classdef App_Practica4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LimiteinferiorEditField         matlab.ui.control.NumericEditField
        LimiteinferiorEditFieldLabel    matlab.ui.control.Label
        LimitesuperiorEditField         matlab.ui.control.NumericEditField
        LimitesuperiorEditFieldLabel    matlab.ui.control.Label
        CombinacionButton               matlab.ui.control.Button
        TransformacionHorizontalButton  matlab.ui.control.Button
        TransformacionVerticalButton    matlab.ui.control.Button
        FactordetransformacionHorizontalEditField  matlab.ui.control.EditField
        FactordetransformacionHorizontalEditFieldLabel  matlab.ui.control.Label
        FactordetransformacionVerticalEditField  matlab.ui.control.EditField
        FactordetransformacionVerticalEditFieldLabel  matlab.ui.control.Label
        MostrarButton                   matlab.ui.control.Button
        FuncinEditField                 matlab.ui.control.EditField
        FuncinEditFieldLabel            matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MostrarButton
        function MostrarButtonPushed(app, event)
            funcStr = app.FuncinEditField.Value;
            Liminf = app.LimiteinferiorEditField.Value;
            Limsup = app.LimitesuperiorEditField.Value;
            func = str2func(['@(x)', funcStr]);
            x = linspace(Liminf,Limsup,1000);
            y = func(x);
            stem(app.UIAxes,x,y);
 
        end

        % Button pushed function: TransformacionVerticalButton
        function TransformacionVerticalButtonPushed(app, event)
            funcStr = app.FuncinEditField.Value;
            Liminf = app.LimiteinferiorEditField.Value;
            Limsup = app.LimitesuperiorEditField.Value;
            factvert = str2double(app.FactordetransformacionVerticalEditField.Value);
            func = str2func(['@(x)', funcStr]);
            x2 = linspace(Liminf,Limsup,1000);
            y2 = func(x2)*factvert;
            stem(app.UIAxes2,x2,y2);
        end

        % Button pushed function: TransformacionHorizontalButton
        function TransformacionHorizontalButtonPushed(app, event)
            funcStr = app.FuncinEditField.Value;
            Liminf = app.LimiteinferiorEditField.Value;
            Limsup = app.LimitesuperiorEditField.Value;
            facthori = str2double(app.FactordetransformacionHorizontalEditField.Value);
            func = str2func(['@(x)', funcStr]);
            x3 = linspace(Liminf,Limsup,1000);
            y3 = func(x3*facthori);
            stem(app.UIAxes3,x3,y3);
        end

        % Button pushed function: CombinacionButton
        function CombinacionButtonPushed(app, event)
            funcStr = app.FuncinEditField.Value;
            Liminf = app.LimiteinferiorEditField.Value;
            Limsup = app.LimitesuperiorEditField.Value;
            factvert = str2double(app.FactordetransformacionVerticalEditField.Value);
            facthori = str2double(app.FactordetransformacionHorizontalEditField.Value);
            func = str2func(['@(x)', funcStr]);
            x5 = linspace(Liminf,Limsup,1000);
            y5 = func(x5*facthori)*factvert;
            stem(app.UIAxes4,x5,y5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Funcion original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Transformacion Vertical')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Transformacion Horizontal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Combinacion')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.FuncinEditFieldLabel = uilabel(app.UIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.UIFigure, 'text');
            app.MostrarButton = uibutton(app.UIFigure, 'push');
            app.MostrarButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarButtonPushed, true);
            app.MostrarButton.Text = 'Mostrar';
            app.FactordetransformacionVerticalEditFieldLabel = uilabel(app.UIFigure);
            app.FactordetransformacionVerticalEditFieldLabel.Text = 'Factor de transformacion Vertical';
            app.FactordetransformacionVerticalEditField = uieditfield(app.UIFigure, 'text');
            app.FactordetransformacionHorizontalEditFieldLabel = uilabel(app.UIFigure);
            app.FactordetransformacionHorizontalEditFieldLabel.Text = 'Factor de transformacion Horizontal';
            app.FactordetransformacionHorizontalEditField = uieditfield(app.UIFigure, 'text');
            app.TransformacionVerticalButton = uibutton(app.UIFigure, 'push');
            app.TransformacionVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @TransformacionVerticalButtonPushed, true);
            app.TransformacionVerticalButton.Text = 'Transformacion Vertical';
            app.TransformacionHorizontalButton = uibutton(app.UIFigure, 'push');
            app.TransformacionHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @TransformacionHorizontalButtonPushed, true);
            app.TransformacionHorizontalButton.Text = 'Transformacion Horizontal';
            app.CombinacionButton = uibutton(app.UIFigure, 'push');
            app.CombinacionButton.ButtonPushedFcn = createCallbackFcn(app, @CombinacionButtonPushed, true);
            app.CombinacionButton.Text = 'Combinacion';
            app.LimitesuperiorEditFieldLabel = uilabel(app.UIFigure);
            app.LimitesuperiorEditFieldLabel.Text = 'Limite superior';
            app.LimitesuperiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.LimiteinferiorEditFieldLabel = uilabel(app.UIFigure);
            app.LimiteinferiorEditFieldLabel.Text = 'Limite inferior';
            app.LimiteinferiorEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Practica4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end